classdef USV_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        FilesTab                      matlab.ui.container.Tab
        testingLoadfilesButton        matlab.ui.control.Button
        testingLoadpathsButton        matlab.ui.control.Button
        PathEditField_Label           matlab.ui.control.EditField
        PathEditField_3Label          matlab.ui.control.Label
        PathEditField_PLabel          matlab.ui.control.EditField
        PathEditField_2Label          matlab.ui.control.Label
        PathEditField_USV             matlab.ui.control.EditField
        PathEditFieldLabel            matlab.ui.control.Label
        SelectLabelFiletxtButton      matlab.ui.control.Button
        SelectProvidedLabelFiletxtButton  matlab.ui.control.Button
        SelectUSVfilewavButton        matlab.ui.control.Button
        VisualisationTab              matlab.ui.container.Tab
        SegmentLength_f               matlab.ui.control.NumericEditField
        SegmentLengthsEditFieldLabel  matlab.ui.control.Label
        SegmentStart_f                matlab.ui.control.NumericEditField
        SegmentStartsEditFieldLabel   matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        UIAxes_Spectrogram            matlab.ui.control.UIAxes
        UIAxes_USV                    matlab.ui.control.UIAxes
        SettingsTab                   matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        VisualisationTab_2            matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        Spectrogram_Colormap_f        matlab.ui.control.DropDown
        ColormapDropDownLabel         matlab.ui.control.Label
        WindowSizeLabel_3             matlab.ui.control.Label
        Spectrogram_FrequencyMax_f    matlab.ui.control.NumericEditField
        WindowSizeLabel_2             matlab.ui.control.Label
        Spectrogram_FrequencyMin_f    matlab.ui.control.NumericEditField
        WindowOverlapLabel            matlab.ui.control.Label
        Spectrogram_WindowOverlap_f   matlab.ui.control.NumericEditField
        SpectrogramSettingsLabel      matlab.ui.control.Label
        Spectrogram_WindowSize_f      matlab.ui.control.NumericEditField
        WindowSizeLabel               matlab.ui.control.Label
        BreakpointButton              matlab.ui.control.Button
        InfoTab                       matlab.ui.container.Tab
        Label_Info                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        USV_Signal_filename              % recorded ultrasonic vocalisation
        USV_Signal_location % Description
        USV_Signal_path         % path where USV_Signal was loaded
      
        USV_ProvidedLabel_path % Description
        
        USV_Signal % Description
        fs

        SegmentStartIndex
        SegmentStopIndex
        USVSegment
    end

    
    methods (Access = private)
        
        
    function labelsStruct = importLabels(labelPath, fs)
        % Function to import label data from a text file and store it in a struct array.
        %
        % Args:
        %   labelPath (string): The path to the label file to be imported.
        %   fs (double): The sampling rate of the audio file.
        %
        % Returns:
        %   labelsStruct (struct array): A struct array where each struct contains
        %   start time, end time, start frequency, end frequency, label, start index, and stop index.
    
        % Define import options
        opts = delimitedTextImportOptions("NumVariables", 3);
    
        % Specify range and delimiter
        opts.DataLines = [1, Inf];
        opts.Delimiter = ["\t", "\\"];
    
        % Specify column names and types
        opts.VariableNames = ["A", "B", "C"];
        opts.VariableTypes = ["string", "string", "string"];
    
        % Specify file level properties
        opts.ExtraColumnsRule = "ignore";
        opts.EmptyLineRule = "read";
        opts.ConsecutiveDelimitersRule = "join";
    
        % Import the data
        tbl = readmatrix(labelPath, opts);
    
        % Extract times, frequencies, and label names
        labelTimes = str2double(tbl(1:2:end, 1:2)); % Start and end times
        labelsFrequencies = str2double(tbl(2:2:end, 2:3)); % Frequencies
        labelsNames = (tbl(1:2:end, 3)); % Labels (names)
    
        % Initialize struct array
        numLabels = size(labelTimes, 1);
        labelsStruct(numLabels) = struct('StartTime', [], 'EndTime', [], 'Label', '', ...
            'StartFrequency', [], 'EndFrequency', [], 'StartIndex', [], 'StopIndex', []); % Preallocation
    
        % Populate the struct array
        for i = 1:numLabels
            % Calculate start and stop indices based on sampling rate
            startIndex = round(labelTimes(i, 1) * fs); % Convert start time to sample index
            stopIndex = round(labelTimes(i, 2) * fs);  % Convert end time to sample index
            
            % Fill the struct
            labelsStruct(i).StartTime = labelTimes(i, 1); % Start time
            labelsStruct(i).EndTime = labelTimes(i, 2);   % End time
            labelsStruct(i).StartIndex = startIndex;       % Start index in samples
            labelsStruct(i).StopIndex = stopIndex;         % Stop index in samples
            labelsStruct(i).Label = labelsNames{i};       % Label name
            labelsStruct(i).StartFrequency = labelsFrequencies(i, 1); % Start frequency
            labelsStruct(i).EndFrequency = labelsFrequencies(i, 2);   % End frequency
            
        end
    
        % Clear temporary variables
        clear opts tbl labelTimes labelsFrequencies labelsNames
    end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectUSVfilewavButton
        function SelectUSVfilewavButtonPushed(app, event)
            

            [app.USV_Signal_filename, app.USV_Signal_location] = uigetfile('*.wav');
            app.USV_Signal_path = [app.USV_Signal_location,app.USV_Signal_filename];
            app.PathEditField_USV.Value = app.USV_Signal_path;

        end

        % Button pushed function: testingLoadpathsButton
        function testingLoadpathsButtonPushed(app, event)
            app.USV_Signal_path = "/Users/gazda/Documents/CTU/Masters/Masters thesis/data/usv_audio/denoise/LPS-SI2homo-mH02-I04-USV_denoise.wav";
            app.USV_ProvidedLabel_path = "/Users/gazda/Documents/CTU/Masters/Masters thesis/data/labels/LPS-SI2homo-mH02-I04-USV-IVojt.txt";

            app.PathEditField_USV.Value = app.USV_Signal_path;
            app.PathEditField_PLabel.Value = app.USV_ProvidedLabel_path;
        end

        % Button pushed function: testingLoadfilesButton
        function testingLoadfilesButtonPushed(app, event)
            
        [app.USV_Signal, app.fs] = audioread(app.USV_Signal_path);
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)


        app.SegmentStartIndex = round(app.SegmentStart_f.Value * app.fs) + 1;
        app.SegmentStopIndex = round((app.SegmentStart_f.Value+app.SegmentLength_f.Value) * app.fs);

        startIndex = app.SegmentStartIndex;
        stopIndex = app.SegmentStopIndex;


        app.USVSegment =  app.USV_Signal(startIndex:stopIndex);

    % Create a time vector for the segment (in seconds)
    segmentTime = (startIndex:stopIndex) / app.fs;

    % Plot the waveform of the audio segment

    
    plot(app.UIAxes_USV, segmentTime, app.USVSegment);

 
    axis(app.UIAxes_USV,'tight')
    grid(app.UIAxes_USV,"on")


% ==================== Spectrogram Calculation and Plotting ====================
% Define spectrogram parameters
windowSize = app.Spectrogram_WindowSize_f.Value;
overlap = app.Spectrogram_WindowOverlap_f.Value;
% Compute spectrogram
[~, f, t, p] = spectrogram(app.USVSegment, windowSize, overlap, [], app.fs, 'yaxis');

% Convert frequency to kHz
f_kHz = f / 1000;

% Ensure `p` is in the right format
p_dB = 10 * log10(abs(p) + eps); % Add a small value `eps` to avoid log(0)

% Correct `imagesc` usage
imagesc(app.UIAxes_Spectrogram, t, f_kHz, p_dB);

% Set axis properties
axis(app.UIAxes_Spectrogram, 'xy');
ylim(app.UIAxes_Spectrogram, [app.Spectrogram_FrequencyMin_f.Value app.Spectrogram_FrequencyMax_f.Value]); % Ultrasonic range
colormap(app.UIAxes_Spectrogram, app.Spectrogram_Colormap_f.Value);
colorbar(app.UIAxes_Spectrogram, "off");

% ==================== Overlay Labels on Spectrogram ====================
%hold on;
% for i = 1:length(labels)
%     % Only plot labels within the specified time segment
%     if labels(i).StartTime >= startTime && labels(i).EndTime <= endTime
%         labelStart = labels(i).StartTime - startTime;
%         labelEnd = labels(i).EndTime - startTime;
% 
%         % Plot vertical lines at label start and end times
%         line([labelStart, labelStart], [0, 120], 'Color', 'r', 'LineWidth', 1, 'LineStyle', '--');
%         line([labelEnd, labelEnd], [0, 120], 'Color', 'r', 'LineWidth', 1, 'LineStyle', '--');
% 
%         % Display label text centered between the lines
%         text((labelStart+labelEnd)/2, 100, labels(i).Label, 'Color', 'red', 'FontSize', 8, ...
%              'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'center');
%     end
% end


        end

        % Button pushed function: BreakpointButton
        function BreakpointButtonPushed(app, event)
           x = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.SelectUSVfilewavButton = uibutton(app.FilesTab, 'push');
            app.SelectUSVfilewavButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUSVfilewavButtonPushed, true);
            app.SelectUSVfilewavButton.Text = 'Select USV file (.wav)';
            app.SelectProvidedLabelFiletxtButton = uibutton(app.FilesTab, 'push');
            app.SelectProvidedLabelFiletxtButton.Text = 'Select Provided Label File (.txt)';
            app.SelectLabelFiletxtButton = uibutton(app.FilesTab, 'push');
            app.SelectLabelFiletxtButton.Text = 'Select Label File (.txt)';
            app.PathEditFieldLabel = uilabel(app.FilesTab);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField_USV = uieditfield(app.FilesTab, 'text');
            app.PathEditField_2Label = uilabel(app.FilesTab);
            app.PathEditField_2Label.Text = 'Path';
            app.PathEditField_PLabel = uieditfield(app.FilesTab, 'text');
            app.PathEditField_3Label = uilabel(app.FilesTab);
            app.PathEditField_3Label.Text = 'Path';
            app.PathEditField_Label = uieditfield(app.FilesTab, 'text');
            app.testingLoadpathsButton = uibutton(app.FilesTab, 'push');
            app.testingLoadpathsButton.ButtonPushedFcn = createCallbackFcn(app, @testingLoadpathsButtonPushed, true);
            app.testingLoadpathsButton.Text = '(testing) Load paths';
            app.testingLoadfilesButton = uibutton(app.FilesTab, 'push');
            app.testingLoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @testingLoadfilesButtonPushed, true);
            app.testingLoadfilesButton.Text = '(testing) Load files';
            app.VisualisationTab = uitab(app.TabGroup);
            app.VisualisationTab.Title = 'Visualisation';
            app.UIAxes_USV = uiaxes(app.VisualisationTab);
            title(app.UIAxes_USV, 'USV - Time')
            xlabel(app.UIAxes_USV, 'Time (s)')
            ylabel(app.UIAxes_USV, 'Amplitude (-)')
            zlabel(app.UIAxes_USV, 'Z')
            app.UIAxes_Spectrogram = uiaxes(app.VisualisationTab);
            title(app.UIAxes_Spectrogram, 'USV - Spectrogram')
            xlabel(app.UIAxes_Spectrogram, 'Time (s)')
            ylabel(app.UIAxes_Spectrogram, 'Frequency (kHz)')
            zlabel(app.UIAxes_Spectrogram, 'Z')
            app.PlotButton = uibutton(app.VisualisationTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SegmentStartsEditFieldLabel = uilabel(app.VisualisationTab);
            app.SegmentStartsEditFieldLabel.Text = 'Segment Start (s)';
            app.SegmentStart_f = uieditfield(app.VisualisationTab, 'numeric');
            app.SegmentStart_f.Value = 60;
            app.SegmentLengthsEditFieldLabel = uilabel(app.VisualisationTab);
            app.SegmentLengthsEditFieldLabel.Text = 'Segment Length (s)';
            app.SegmentLength_f = uieditfield(app.VisualisationTab, 'numeric');
            app.SegmentLength_f.Value = 10;
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.BreakpointButton = uibutton(app.SettingsTab, 'push');
            app.BreakpointButton.ButtonPushedFcn = createCallbackFcn(app, @BreakpointButtonPushed, true);
            app.BreakpointButton.Text = 'Breakpoint';
            app.TabGroup2 = uitabgroup(app.SettingsTab);
            app.VisualisationTab_2 = uitab(app.TabGroup2);
            app.VisualisationTab_2.Title = 'Visualisation';
            app.GridLayout = uigridlayout(app.VisualisationTab_2);
            app.WindowSizeLabel = uilabel(app.GridLayout);
            app.WindowSizeLabel.Text = 'Window Size  (-)';
            app.Spectrogram_WindowSize_f = uieditfield(app.GridLayout, 'numeric');
            app.Spectrogram_WindowSize_f.Value = 1024;
            app.SpectrogramSettingsLabel = uilabel(app.GridLayout);
            app.SpectrogramSettingsLabel.Text = 'Spectrogram Settings';
            app.Spectrogram_WindowOverlap_f = uieditfield(app.GridLayout, 'numeric');
            app.Spectrogram_WindowOverlap_f.Value = 512;
            app.WindowOverlapLabel = uilabel(app.GridLayout);
            app.WindowOverlapLabel.Text = 'Window Overlap  (-)';
            app.Spectrogram_FrequencyMin_f = uieditfield(app.GridLayout, 'numeric');
            app.Spectrogram_FrequencyMin_f.Value = 40;
            app.WindowSizeLabel_2 = uilabel(app.GridLayout);
            app.WindowSizeLabel_2.Text = 'Frequency Min  (kHz)';
            app.Spectrogram_FrequencyMax_f = uieditfield(app.GridLayout, 'numeric');
            app.Spectrogram_FrequencyMax_f.Value = 120;
            app.WindowSizeLabel_3 = uilabel(app.GridLayout);
            app.WindowSizeLabel_3.Text = 'Frequency Max  (kHz)';
            app.ColormapDropDownLabel = uilabel(app.GridLayout);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.Spectrogram_Colormap_f = uidropdown(app.GridLayout);
            app.Spectrogram_Colormap_f.Items = {'parula', 'turbo', 'hsv', 'hot', 'cool', 'spring', 'summer', 'winter', 'gray', 'jet'};
            app.Spectrogram_Colormap_f.Value = 'hot';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.Label_Info = uilabel(app.InfoTab);
            app.Label_Info.Text = {'Master Thesis: Antonín Gazda'; ''; '2024/25'; ''; 'Supervisor: prof. Ing. Roman Čmejla, CSc.'; ''; 'CTU FEE'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = USV_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end